classdef RobotControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EndProgrammeButton              matlab.ui.control.StateButton
        ResumeProgrammeButton           matlab.ui.control.StateButton
        estopWarning                    matlab.ui.control.Label
        BOOTBOOZEBOTButton              matlab.ui.control.Button
        EStop                           matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        AutonomousModeTab               matlab.ui.container.Tab
        ToggleSceneButton               matlab.ui.control.StateButton
        StatusLabel                     matlab.ui.control.Label
        AutonomousModeActiveLamp        matlab.ui.control.Lamp
        AutonomousModeActiveLampLabel   matlab.ui.control.Label
        EStopPushedLamp                 matlab.ui.control.Lamp
        EStopPushedLamp_2Label          matlab.ui.control.Label
        CollisionDetectedLamp           matlab.ui.control.Lamp
        CollisionDetectedLampLabel      matlab.ui.control.Label
        AutonomousModeLabel             matlab.ui.control.Label
        TaskCompletedLamp               matlab.ui.control.Lamp
        EStopPushedLampLabel            matlab.ui.control.Label
        SupermoistButton                matlab.ui.control.Button
        BigCreaturesPaleButton          matlab.ui.control.Button
        RooheysButton                   matlab.ui.control.Button
        ServeadeliciousbevvyLabel       matlab.ui.control.Label
        LashesButton                    matlab.ui.control.Button
        UR3ControllerTab                matlab.ui.container.Tab
        ur3ZNeg                         matlab.ui.control.StateButton
        ur3ZPos                         matlab.ui.control.StateButton
        ur3YNeg                         matlab.ui.control.StateButton
        ur3YPos                         matlab.ui.control.StateButton
        ur3XNeg                         matlab.ui.control.StateButton
        ur3XPos                         matlab.ui.control.StateButton
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ur3Joint6                       matlab.ui.control.Slider
        Joint6SliderLabel               matlab.ui.control.Label
        ur3Joint5                       matlab.ui.control.Slider
        Joint5SliderLabel               matlab.ui.control.Label
        ur3Joint4                       matlab.ui.control.Slider
        Joint4SliderLabel               matlab.ui.control.Label
        ur3Joint3                       matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        ur3Joint2                       matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        ur3Joint1                       matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        ur3Speed                        matlab.ui.control.Knob
        EndeffectorstepsizemKnobLabel   matlab.ui.control.Label
        JointmovementLabel              matlab.ui.control.Label
        CartesianmovementGlobalLabel    matlab.ui.control.Label
        UR3ManualControlLabel           matlab.ui.control.Label
        CR5ControllerTab                matlab.ui.container.Tab
        cr5Joint6                       matlab.ui.control.Slider
        Joint6Slider_2Label             matlab.ui.control.Label
        cr5Joint5                       matlab.ui.control.Slider
        Joint5Slider_2Label             matlab.ui.control.Label
        cr5Joint4                       matlab.ui.control.Slider
        Joint4Slider_2Label             matlab.ui.control.Label
        cr5Joint3                       matlab.ui.control.Slider
        Joint3Slider_2Label             matlab.ui.control.Label
        cr5Joint2                       matlab.ui.control.Slider
        Joint2Slider_2Label             matlab.ui.control.Label
        cr5Joint1                       matlab.ui.control.Slider
        Joint1Slider_2Label             matlab.ui.control.Label
        cr5Speed                        matlab.ui.control.Knob
        EndeffectorstepsizemKnobLabel_2  matlab.ui.control.Label
        cr5ZNeg                         matlab.ui.control.StateButton
        cr5ZPos                         matlab.ui.control.StateButton
        cr5YNeg                         matlab.ui.control.StateButton
        cr5YPos                         matlab.ui.control.StateButton
        cr5XNeg                         matlab.ui.control.StateButton
        cr5XPos                         matlab.ui.control.StateButton
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        JointmovementLabel_2            matlab.ui.control.Label
        CartesianmovementGlobalLabel_2  matlab.ui.control.Label
        CR5ManualControlLabel           matlab.ui.control.Label
        CollisionControlTab             matlab.ui.container.Tab
        TogglehandButton                matlab.ui.control.StateButton
        CollisionDetectionLabel         matlab.ui.control.Label
        LightCurtainLabel               matlab.ui.control.Label
        TogglehumanobstructionButton    matlab.ui.control.StateButton
        zSlider                         matlab.ui.control.Slider
        zSliderLabel                    matlab.ui.control.Label
        ySlider                         matlab.ui.control.Slider
        ySliderLabel                    matlab.ui.control.Label
        ObjectPositionLabel             matlab.ui.control.Label
        xSlider                         matlab.ui.control.Slider
        xSliderLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        task; %!< the class handle

        % Joystick
        joy; %!< joystick object used to access the state of the buttons

        lastPos_ur; %!< position of the UR3 before being moved via manual control mode
        lastPos_cr; %!< position of the CR5 before being moved via manual control mode

        % collision detection hand
        lightCurtain; %!< light curtain positions
        lightCurtain_h; %!< light curtain handle

        handBaseVertices; %!< the base vertices of the hand from plyread
        handVertices; %!< the vertices of the hand after being transformed to positions indicated by the app sliders
        handPos; %!< the xyz position of the hand
        han; %!< the hand object

    end
    
    methods (Access = public)

        %% When a collision is detected, activate the estop and action the estop status 
        % function is called by the a3 class
        function actionCollisionDetection(app,lastpos)

            app.CollisionDetectedLamp.Enable = "on";
            app.EStop.Value = true;
            app.EStopValueChanged;
            app.actionEStopStatus(lastpos);
        end

        %% getter for the status of the human spawn in and collision detection features
        function status = getColButStatus(app)
            status = app.TogglehumanobstructionButton.Value;
        end

        %% Checks whether the estop is pressed, and handles the case accordingly, called by the a3 class
        function actionEStopStatus(app,lastPos)
            % lastPos --> 2 x n vector where the first row is the ur3 joint
            % positions and the second is the cr5 joint positions

            hardwareEStop = 0; % in case controller is not connected
            try hardwareEStop = button(app.joy,3); end;
            % check the status of the joystick
            if hardwareEStop == 1
                disp('HARDWARE ESTOP PRESSED');
                app.EStop.Value = true;
                app.EStopValueChanged;
            end

            if app.EStop.Value 
                % run the class in e-stop mode
                disp('running in e-stop mode ...');

                % store the next joint position to go to once programme is
                % resumed
                if nargin > 1
                    app.lastPos_ur = lastPos(1,:);
                    app.lastPos_cr = lastPos(2,:);
                end

                % turn the e-stop light on and the autonomous light off
                app.EStopPushedLamp.Enable = "on";
                app.AutonomousModeActiveLamp.Enable = "off";

                % disable the manual control buttons
                app.enableManualControl("off");
                
                % wait for the EStop to be released after a short cooldown
                pause(0.5);
                oldHardwareEStop = false;

                % checks for hardware e-stop to twist out the e-stop on
                % the pendant
                while app.EStop.Value == true

                    try newHardwareEStop = button(app.joy,3); end

                    if oldHardwareEStop && not(newHardwareEStop)
                        app.EStop.Value = false;
                        app.EStopValueChanged;
                    end

                    try oldHardwareEStop = newHardwareEStop; end

                    pause(0.01);
                end

                % turn the e-stop light off
                app.EStopPushedLamp.Enable = "off";

                % hand over control to the manual UI
                app.manualControl;
                app.AutonomousModeActiveLamp.Enable = "on";
                app.CollisionDetectedLamp.Enable = "off";
            end

        end

        %% sends control to the advanced teach sliders in the gui when called, holds control manually until the resume button is pressed
        function manualControl(app,event)

            % enable the manual control buttons
            app.enableManualControl("on");

            while app.ResumeProgrammeButton.Value == false

                stepFlag = 1;

                % check for e-stop input
                app.actionEStopStatus;
                

                % CHECK THE UR3 CONTROLLER FOR MANUAL INPUTS                
                if app.ur3XPos.Value
                    % POSITIVE X DIRECTION
                    T = transl(app.ur3Speed.Value,0,0);
                    robot = app.task.UR;
                    app.ur3XPos.Value = false;

                elseif app.ur3XNeg.Value
                    % NEGATIVE X DIRECTION
                    T = transl(-app.ur3Speed.Value,0,0);
                    robot = app.task.UR;
                    app.ur3XNeg.Value = false;

                elseif app.ur3YPos.Value
                    % POSITIVE Y DIRECTION
                    T = transl(0,app.ur3Speed.Value,0);
                    robot = app.task.UR;
                    app.ur3YPos.Value = false;

                elseif app.ur3YNeg.Value
                    % NEGATIVE Y DIRECTION
                    T = transl(0,-app.ur3Speed.Value,0);
                    robot = app.task.UR;
                    app.ur3YNeg.Value = false;   

                elseif app.ur3ZPos.Value
                    % POSITIVE Z DIRECTION
                    T = transl(0,0,app.ur3Speed.Value);
                    robot = app.task.UR;
                    app.ur3ZPos.Value = false;

                elseif app.ur3ZNeg.Value
                    % NEGATIVE Z DIRECTION
                    T = transl(0,0,-app.ur3Speed.Value);
                    robot = app.task.UR;
                    app.ur3ZNeg.Value = false;                     

                % CHECK THE CR5 CONTROLLER FOR MANUAL INPUTS
                elseif app.cr5XPos.Value
                    % POSITIVE X DIRECTION
                    T = transl(app.cr5Speed.Value,0,0);
                    robot = app.task.CO;
                    app.cr5XPos.Value = false;

                elseif app.cr5XNeg.Value
                    % NEGATIVE X DIRECTION
                    T = transl(-app.cr5Speed.Value,0,0);
                    robot = app.task.CO;
                    app.cr5XNeg.Value = false;

                elseif app.cr5YPos.Value
                    % POSITIVE Y DIRECTION
                    T = transl(0,app.cr5Speed.Value,0);
                    robot = app.task.CO;
                    app.cr5YPos.Value = false;

                elseif app.cr5YNeg.Value
                    % NEGATIVE Y DIRECTION
                    T = transl(0,-app.cr5Speed.Value,0);
                    robot = app.task.CO;
                    app.cr5YNeg.Value = false;   

                elseif app.cr5ZPos.Value
                    % POSITIVE Z DIRECTION
                    T = transl(0,0,app.cr5Speed.Value);
                    robot = app.task.CO;
                    app.cr5ZPos.Value = false;

                elseif app.cr5ZNeg.Value
                    % NEGATIVE Z DIRECTION
                    T = transl(0,0,-app.cr5Speed.Value);
                    robot = app.task.CO;
                    app.cr5ZNeg.Value = false;                     

                else
                    % if no button is pressed, prevent any action from
                    % happening
                    stepFlag = 0;

                end


                % if a button was pressed, action the movement
                if stepFlag == 1
                    app.task.oneStep(robot,T);
                end

                pause(0.001);

            end
            
            % disable the manual controls
            app.enableManualControl("off");

            % return to the original position to resume the programme using
            % the last known position of the robots (lastPos_ur and
            % lastPos_cr)
            steps = 50;

            q1_ur = app.task.UR.getpos;
            q2_ur = app.lastPos_ur;
            qMatrix_ur = jtraj(q1_ur,q2_ur,steps);

            q1_cr = app.task.CO.getpos;
            q2_cr = app.lastPos_cr;
            qMatrix_cr = jtraj(q1_cr,q2_cr,steps);

            for i = 1:steps

                %animate through the qMatrix
                app.task.UR.animate(qMatrix_ur(i,:));
                app.task.CO.animate(qMatrix_cr(i,:));

                % update the joint sliders in the gui
                app.updateJointSliders;

                % animate the gripper movements
                urEndEffector = app.task.UR.fkine(qMatrix_ur(i,:)).T;
                animateGripper(app.task.URgrip,app.task.URpos,urEndEffector)
                crEndEffector = app.task.CO.fkine(qMatrix_cr(i,:)).T;
                animateGripper(app.task.COgrip,app.task.COpos,crEndEffector);
                
                % pause to slow animation down
                pause(0.001);

            end


        end
    
        %% getter for the status of the estop
        function status = getEStopStatus(app,event)

            status = app.EStop.Value;
        end

        %% updates the value of the gui sliders so the joint position values animate while the robot is in autonomous motion
        function updateJointSliders(app)

            % get the joint values of both robots
            q_ur = app.task.UR.getpos;
            q_cr = app.task.CO.getpos;

            % change the value of the sliders

            % UR3 sliders
            app.ur3Joint1.Value = q_ur(1);
            app.ur3Joint2.Value = q_ur(2);
            app.ur3Joint3.Value = q_ur(3);
            app.ur3Joint4.Value = q_ur(4);
            app.ur3Joint5.Value = q_ur(5);
            app.ur3Joint6.Value = q_ur(6);

            % CR5 sliders
            app.cr5Joint1.Value = q_cr(1);
            app.cr5Joint2.Value = q_cr(2);
            app.cr5Joint3.Value = q_cr(3);
            app.cr5Joint4.Value = q_cr(4);
            app.cr5Joint5.Value = q_cr(5);
            app.cr5Joint6.Value = q_cr(6);            

            
        end 

        %% animates the hand based on the value in the xyz sliders
        function animateHand(app)

            % store the xyz positions in the sliders
            app.handPos = [app.xSlider.Value, app.ySlider.Value, app.zSlider.Value];

            % animate the hand movement
            T2 = transl(app.handPos(1),app.handPos(2),app.handPos(3));

            app.handVertices = [app.handBaseVertices, ones(size(app.handBaseVertices,1),1)]*T2';
            set(app.han, 'Vertices', app.handVertices(:,1:3));

        end
        
        %% checks whether the light curtain has been broken
        function curtBreak = checkLightCurtain(app)

            radii = [0.15,0.18,0.1];

            algebraicDist = ((app.lightCurtain(:,1) - app.handPos(1)) / radii(1)).^2 ...
                + ((app.lightCurtain(:,2) - app.handPos(2)) / radii(2)).^2 ...
                + ((app.lightCurtain(:,3) - app.handPos(3)) / radii(3)).^2;
            pointsInside = find(algebraicDist < 1);

            if size(pointsInside, 1) > 1
                curtBreak = true;
            else
                curtBreak = false;
            end

        end
        
    end
    
    methods (Access = private)
        
        %% animates the robots based on the value in the gui joint angle sliders
        function animateJointAngles(app)

            % store the joint positions in the sliders
            q_cr = [app.cr5Joint1.Value app.cr5Joint2.Value app.cr5Joint3.Value app.cr5Joint4.Value app.cr5Joint5.Value app.cr5Joint6.Value];
            q_ur = [app.ur3Joint1.Value app.ur3Joint2.Value app.ur3Joint3.Value app.ur3Joint4.Value app.ur3Joint5.Value app.ur3Joint6.Value];

            % animate the robot arms and grippers

            % animate the ur3
            app.task.UR.animate(q_ur);
            urEndEffector = app.task.UR.fkine(q_ur).T;
            animateGripper(app.task.URgrip,app.task.URpos,urEndEffector)

            % animate the dobot
            crEndEffector = app.task.CO.fkine(q_cr).T;
            app.task.CO.animate(q_cr);
            animateGripper(app.task.COgrip,app.task.COpos,crEndEffector);

            
        end

        function enableManualControl(app,status)
            %% Enables or disables buttons for manual control
            % status [STR] --> "on" or "off"

            % UR3 Controls - RMRC
            app.ur3ZNeg.Enable = status;
            app.ur3ZPos.Enable = status;

            app.ur3YNeg.Enable = status;
            app.ur3YPos.Enable = status;

            app.ur3XNeg.Enable = status;
            app.ur3XPos.Enable = status;

            % UR3 Controls - Joints
            app.ur3Joint1.Enable = status;
            app.ur3Joint2.Enable = status;
            app.ur3Joint3.Enable = status;
            app.ur3Joint4.Enable = status;
            app.ur3Joint5.Enable = status;
            app.ur3Joint6.Enable = status;

            %CR5 Controls - RMRC
            app.cr5ZNeg.Enable = status;
            app.cr5ZPos.Enable = status;

            app.cr5YNeg.Enable = status;
            app.cr5YPos.Enable = status;

            app.cr5XNeg.Enable = status;
            app.cr5XPos.Enable = status;

            % CR5 Controls - Joints
            app.cr5Joint1.Enable = status;
            app.cr5Joint2.Enable = status;
            app.cr5Joint3.Enable = status;
            app.cr5Joint4.Enable = status;
            app.cr5Joint5.Enable = status;
            app.cr5Joint6.Enable = status;

            
        end

        function enableBeerSelection(app,status)
            %% Enables or disables buttons for selecting beer
            % status [STR] --> "on" or "off"
            app.SupermoistButton.Enable = status;
            app.BigCreaturesPaleButton.Enable = status;
            app.RooheysButton.Enable = status;
            app.LashesButton.Enable = status;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EStop
        function EStopValueChanged(app, event)
            % if the estop has been pressed
            if app.EStop.Value
                app.EStop.Text = '';
                app.estopWarning.Visible = "on";
                app.estopWarning.Enable = "on";
                app.ResumeProgrammeButton.Enable = "off";
                app.ResumeProgrammeButton.Value = false;
                app.ResumeProgrammeButton.Text = "Release E-Stop to resume programme";
                app.EndProgrammeButton.Enable = "off";
                app.EndProgrammeButton.Value = false;
                app.EndProgrammeButton.Text = "Release E-Stop to end programme";
            
            % if the estop has been released
            else
                app.EStop.Text = '';
                app.estopWarning.Visible = "off";
                app.estopWarning.Enable = "off";
                app.ResumeProgrammeButton.Enable = "on";
                app.ResumeProgrammeButton.Text = "Resume Programme";
                app.EndProgrammeButton.Enable = "on";
                app.EndProgrammeButton.Text = "End Programme";                
            end
        end

        % Button pushed function: LashesButton
        function LashesButtonPushed(app, event)
            % make visual changes in the gui while task is running
            app.enableBeerSelection("off");
            app.task.chooseBeer(1);
            app.AutonomousModeActiveLamp.Enable = "on";
            app.TaskCompletedLamp.Enable = "off";

            % run the demo
            app.task.serveBeer;

            % make visual changes in the gui while task has been completed
            app.AutonomousModeActiveLamp.Enable = "off";
            app.TaskCompletedLamp.Enable = "on";
            app.enableBeerSelection("on");
        end

        % Button pushed function: BOOTBOOZEBOTButton
        function BOOTBOOZEBOTButtonPushed(app, event)
            % instantiate the a3 class
            app.task = a3(app);

            % create the joystick object if there is a controller connected
            try app.joy = vrjoystick(1); end

            % enable autonomous beer serving
            app.enableBeerSelection("on");

            app.cr5Joint6.Limits = app.task.CO.qlim(6,:);
            app.cr5Joint5.Limits = app.task.CO.qlim(5,:);
            app.cr5Joint4.Limits = app.task.CO.qlim(4,:);
            app.cr5Joint3.Limits = app.task.CO.qlim(3,:);
            app.cr5Joint2.Limits = app.task.CO.qlim(2,:);
            app.cr5Joint1.Limits = app.task.CO.qlim(1,:);

            app.ur3Joint6.Limits = app.task.UR.qlim(6,:);
            app.ur3Joint5.Limits = app.task.UR.qlim(5,:);
            app.ur3Joint4.Limits = app.task.UR.qlim(4,:);
            app.ur3Joint3.Limits = app.task.UR.qlim(3,:);
            app.ur3Joint2.Limits = app.task.UR.qlim(2,:);
            app.ur3Joint1.Limits = app.task.UR.qlim(1,:);
        end

        % Value changed function: ResumeProgrammeButton
        function ResumeProgrammeButtonValueChanged(app, event)

            % if this button is pressed and the estop is released, the
            % programme will resume
            if app.EStop == false
                app.ResumeProgrammeButton.Enable = "off";
            end
            
            
        end

        % Value changed function: EndProgrammeButton
        function EndProgrammeButtonValueChanged(app, event)
            value = app.EndProgrammeButton.Value;
            
            % delete the a3 class if this button is pressed
            if app.EStop == false
                app.EndProgrammeButton.enable = "off";
            end

            delete(app.task);
        end

        % Value changed function: ur3XPos
        function UPur3XPosPushed(app, event)
            value = app.ur3XPos.Value;

        end

        % Value changing function: cr5Joint1, cr5Joint2, cr5Joint3, 
        % ...and 9 other components
        function cr5Joint6ValueChanging(app, event)
            app.animateJointAngles;
        end

        % Button pushed function: RooheysButton
        function RooheysButtonPushed(app, event)
            app.enableBeerSelection("off");
            app.task.chooseBeer(2);
            app.AutonomousModeActiveLamp.Enable = "on";
            app.TaskCompletedLamp.Enable = "off";

            % run the demo
            app.task.serveBeer;

            app.AutonomousModeActiveLamp.Enable = "off";
            app.TaskCompletedLamp.Enable = "on";
            app.enableBeerSelection("on");
        end

        % Button pushed function: BigCreaturesPaleButton
        function BigCreaturesPaleButtonPushed(app, event)
            app.enableBeerSelection("off");
            app.task.chooseBeer(3);
            app.AutonomousModeActiveLamp.Enable = "on";
            app.TaskCompletedLamp.Enable = "off";

            % run the demo
            app.task.serveBeer;

            app.AutonomousModeActiveLamp.Enable = "off";
            app.TaskCompletedLamp.Enable = "on";
            app.enableBeerSelection("on");
        end

        % Button pushed function: SupermoistButton
        function SupermoistButtonPushed(app, event)
            app.enableBeerSelection("off");
            app.task.chooseBeer(4);
            app.AutonomousModeActiveLamp.Enable = "on";
            app.TaskCompletedLamp.Enable = "off";

            % run the demo
            app.task.serveBeer;

            app.AutonomousModeActiveLamp.Enable = "off";
            app.TaskCompletedLamp.Enable = "on";
            app.enableBeerSelection("on");
        end

        % Value changing function: xSlider, ySlider, zSlider
        function xSliderValueChanging(app, event)
            app.animateHand; 

        end

        % Value changed function: TogglehandButton
        function TogglehandButtonValueChanged(app, event)
            value = app.TogglehandButton.Value;
            
            if value

                startpos = [0,0.5,0.75];

                app.han = PlaceObject('hand.ply',startpos);
                [~,app.handBaseVertices,~] = plyread('hand.ply','tri');

                % create the light curtain
                offset = 0.84;  % 0.8400m off ground
                [X, Z] = meshgrid(-0.95:0.1:0.95, (-0.15+offset):0.1:(0.15+offset)); % Adjust the ranges to create a 1.9m x 0.3m plane
                Y = zeros(size(X)); % Set Z equal to X to create a 2D plane
                % app.lightCurtain_h = surf(X,Y,Z);
                app.lightCurtain = [X(:), Y(:), Z(:)];

                % enable the slider
                app.xSlider.Enable = "on";
                app.ySlider.Enable = "on";
                app.zSlider.Enable = "on";
            else 
                % turn off all hand features
                app.xSlider.Enable = "off";
                app.ySlider.Enable = "off";
                app.zSlider.Enable = "off";
                delete(app.han);
                app.lightCurtain = 0;
                app.handVertices = 0;
                app.handBaseVertices = 0;
            end

        end

        % Value changed function: TogglehumanobstructionButton
        function TogglehumanobstructionButtonValueChanged(app, event)
            value = app.TogglehumanobstructionButton.Value;
            
            if value
                app.task.CollisionDemo;
            else
                app.task.CollisionDemoEnd;
            end
        end

        % Value changed function: ToggleSceneButton
        function ToggleSceneButtonValueChanged(app, event)
            value = app.ToggleSceneButton.Value;
            
            if value
                axis([-1,1,0,1.5,0.5,1.5]); %close up
                
            else
                axis([-2,2,-2,2,0,2]); %full View
            end
            
            view(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AutonomousModeTab = uitab(app.TabGroup);
            app.AutonomousModeTab.Title = 'Autonomous Mode';
            app.LashesButton = uibutton(app.AutonomousModeTab, 'push');
            app.LashesButton.ButtonPushedFcn = createCallbackFcn(app, @LashesButtonPushed, true);
            app.LashesButton.Enable = 'off';
            app.LashesButton.Text = '350 Lashes';
            app.ServeadeliciousbevvyLabel = uilabel(app.AutonomousModeTab);
            app.ServeadeliciousbevvyLabel.Text = 'Serve a delicious bevvy:';
            app.RooheysButton = uibutton(app.AutonomousModeTab, 'push');
            app.RooheysButton.ButtonPushedFcn = createCallbackFcn(app, @RooheysButtonPushed, true);
            app.RooheysButton.Enable = 'off';
            app.RooheysButton.Text = 'Rooheys';
            app.BigCreaturesPaleButton = uibutton(app.AutonomousModeTab, 'push');
            app.BigCreaturesPaleButton.ButtonPushedFcn = createCallbackFcn(app, @BigCreaturesPaleButtonPushed, true);
            app.BigCreaturesPaleButton.Enable = 'off';
            app.BigCreaturesPaleButton.Text = 'Big Creatures Pale';
            app.SupermoistButton = uibutton(app.AutonomousModeTab, 'push');
            app.SupermoistButton.ButtonPushedFcn = createCallbackFcn(app, @SupermoistButtonPushed, true);
            app.SupermoistButton.Enable = 'off';
            app.SupermoistButton.Text = 'Supermoist';
            app.EStopPushedLampLabel = uilabel(app.AutonomousModeTab);
            app.EStopPushedLampLabel.Text = 'Task Completed';
            app.TaskCompletedLamp = uilamp(app.AutonomousModeTab);
            app.TaskCompletedLamp.Enable = 'off';
            app.AutonomousModeLabel = uilabel(app.AutonomousModeTab);
            app.AutonomousModeLabel.Text = 'Autonomous Mode';
            app.CollisionDetectedLampLabel = uilabel(app.AutonomousModeTab);
            app.CollisionDetectedLampLabel.Text = 'Collision Detected';
            app.CollisionDetectedLamp = uilamp(app.AutonomousModeTab);
            app.CollisionDetectedLamp.Enable = 'off';
            app.EStopPushedLamp_2Label = uilabel(app.AutonomousModeTab);
            app.EStopPushedLamp_2Label.Text = 'E-Stop Pushed';
            app.EStopPushedLamp = uilamp(app.AutonomousModeTab);
            app.EStopPushedLamp.Enable = 'off';
            app.AutonomousModeActiveLampLabel = uilabel(app.AutonomousModeTab);
            app.AutonomousModeActiveLampLabel.Text = 'Autonomous Mode Active';
            app.AutonomousModeActiveLamp = uilamp(app.AutonomousModeTab);
            app.AutonomousModeActiveLamp.Enable = 'off';
            app.StatusLabel = uilabel(app.AutonomousModeTab);
            app.StatusLabel.Text = 'Status';
            app.ToggleSceneButton = uibutton(app.AutonomousModeTab, 'state');
            app.ToggleSceneButton.ValueChangedFcn = createCallbackFcn(app, @ToggleSceneButtonValueChanged, true);
            app.ToggleSceneButton.Text = 'Toggle Scene';
            app.UR3ControllerTab = uitab(app.TabGroup);
            app.UR3ControllerTab.Title = 'UR3 Controller';
            app.UR3ManualControlLabel = uilabel(app.UR3ControllerTab);
            app.UR3ManualControlLabel.Text = 'UR3 Manual Control';
            app.CartesianmovementGlobalLabel = uilabel(app.UR3ControllerTab);
            app.CartesianmovementGlobalLabel.Text = 'Cartesian movement (Global):';
            app.JointmovementLabel = uilabel(app.UR3ControllerTab);
            app.JointmovementLabel.Text = 'Joint movement:';
            app.EndeffectorstepsizemKnobLabel = uilabel(app.UR3ControllerTab);
            app.EndeffectorstepsizemKnobLabel.Text = 'End effector step size (m)';
            app.ur3Speed = uiknob(app.UR3ControllerTab, 'continuous');
            app.ur3Speed.Limits = [0 0.1];
            app.ur3Speed.Value = 0.01;
            app.Joint1SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint1SliderLabel.Enable = 'off';
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.ur3Joint1 = uislider(app.UR3ControllerTab);
            app.ur3Joint1.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.ur3Joint1.Enable = 'off';
            app.Joint2SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint2SliderLabel.Enable = 'off';
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.ur3Joint2 = uislider(app.UR3ControllerTab);
            app.ur3Joint2.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.ur3Joint2.Enable = 'off';
            app.Joint3SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint3SliderLabel.Enable = 'off';
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.ur3Joint3 = uislider(app.UR3ControllerTab);
            app.ur3Joint3.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.ur3Joint3.Enable = 'off';
            app.Joint4SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint4SliderLabel.Enable = 'off';
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.ur3Joint4 = uislider(app.UR3ControllerTab);
            app.ur3Joint4.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.ur3Joint4.Enable = 'off';
            app.Joint5SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint5SliderLabel.Enable = 'off';
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.ur3Joint5 = uislider(app.UR3ControllerTab);
            app.ur3Joint5.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.ur3Joint5.Enable = 'off';
            app.Joint6SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint6SliderLabel.Enable = 'off';
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.ur3Joint6 = uislider(app.UR3ControllerTab);
            app.ur3Joint6.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.ur3Joint6.Enable = 'off';
            app.XLabel = uilabel(app.UR3ControllerTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UR3ControllerTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UR3ControllerTab);
            app.ZLabel.Text = 'Z';
            app.ur3XPos = uibutton(app.UR3ControllerTab, 'state');
            app.ur3XPos.ValueChangedFcn = createCallbackFcn(app, @UPur3XPosPushed, true);
            app.ur3XPos.Enable = 'off';
            app.ur3XPos.Text = '+';
            app.ur3XNeg = uibutton(app.UR3ControllerTab, 'state');
            app.ur3XNeg.Enable = 'off';
            app.ur3XNeg.Text = '-';
            app.ur3YPos = uibutton(app.UR3ControllerTab, 'state');
            app.ur3YPos.Enable = 'off';
            app.ur3YPos.Text = '+';
            app.ur3YNeg = uibutton(app.UR3ControllerTab, 'state');
            app.ur3YNeg.Enable = 'off';
            app.ur3YNeg.Text = '-';
            app.ur3ZPos = uibutton(app.UR3ControllerTab, 'state');
            app.ur3ZPos.Enable = 'off';
            app.ur3ZPos.Text = '+';
            app.ur3ZNeg = uibutton(app.UR3ControllerTab, 'state');
            app.ur3ZNeg.Enable = 'off';
            app.ur3ZNeg.Text = '-';
            app.CR5ControllerTab = uitab(app.TabGroup);
            app.CR5ControllerTab.Title = 'CR5 Controller';
            app.CR5ManualControlLabel = uilabel(app.CR5ControllerTab);
            app.CR5ManualControlLabel.Text = 'CR5 Manual Control';
            app.CartesianmovementGlobalLabel_2 = uilabel(app.CR5ControllerTab);
            app.CartesianmovementGlobalLabel_2.Text = 'Cartesian movement (Global):';
            app.JointmovementLabel_2 = uilabel(app.CR5ControllerTab);
            app.JointmovementLabel_2.Text = 'Joint movement:';
            app.XLabel_2 = uilabel(app.CR5ControllerTab);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.CR5ControllerTab);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.CR5ControllerTab);
            app.ZLabel_2.Text = 'Z';
            app.cr5XPos = uibutton(app.CR5ControllerTab, 'state');
            app.cr5XPos.Enable = 'off';
            app.cr5XPos.Text = '+';
            app.cr5XNeg = uibutton(app.CR5ControllerTab, 'state');
            app.cr5XNeg.Enable = 'off';
            app.cr5XNeg.Text = '-';
            app.cr5YPos = uibutton(app.CR5ControllerTab, 'state');
            app.cr5YPos.Enable = 'off';
            app.cr5YPos.Text = '+';
            app.cr5YNeg = uibutton(app.CR5ControllerTab, 'state');
            app.cr5YNeg.Enable = 'off';
            app.cr5YNeg.Text = '-';
            app.cr5ZPos = uibutton(app.CR5ControllerTab, 'state');
            app.cr5ZPos.Enable = 'off';
            app.cr5ZPos.Text = '+';
            app.cr5ZNeg = uibutton(app.CR5ControllerTab, 'state');
            app.cr5ZNeg.Enable = 'off';
            app.cr5ZNeg.Text = '-';
            app.EndeffectorstepsizemKnobLabel_2 = uilabel(app.CR5ControllerTab);
            app.EndeffectorstepsizemKnobLabel_2.Text = 'End effector step size (m)';
            app.cr5Speed = uiknob(app.CR5ControllerTab, 'continuous');
            app.cr5Speed.Limits = [0 0.1];
            app.cr5Speed.Value = 0.01;
            app.Joint1Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint1Slider_2Label.Enable = 'off';
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.cr5Joint1 = uislider(app.CR5ControllerTab);
            app.cr5Joint1.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.cr5Joint1.Enable = 'off';
            app.Joint2Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint2Slider_2Label.Enable = 'off';
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.cr5Joint2 = uislider(app.CR5ControllerTab);
            app.cr5Joint2.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.cr5Joint2.Enable = 'off';
            app.Joint3Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint3Slider_2Label.Enable = 'off';
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.cr5Joint3 = uislider(app.CR5ControllerTab);
            app.cr5Joint3.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.cr5Joint3.Enable = 'off';
            app.Joint4Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint4Slider_2Label.Enable = 'off';
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.cr5Joint4 = uislider(app.CR5ControllerTab);
            app.cr5Joint4.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.cr5Joint4.Enable = 'off';
            app.Joint5Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint5Slider_2Label.Enable = 'off';
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.cr5Joint5 = uislider(app.CR5ControllerTab);
            app.cr5Joint5.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.cr5Joint5.Enable = 'off';
            app.Joint6Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint6Slider_2Label.Enable = 'off';
            app.Joint6Slider_2Label.Text = 'Joint 6';
            app.cr5Joint6 = uislider(app.CR5ControllerTab);
            app.cr5Joint6.ValueChangingFcn = createCallbackFcn(app, @cr5Joint6ValueChanging, true);
            app.cr5Joint6.Enable = 'off';
            app.CollisionControlTab = uitab(app.TabGroup);
            app.CollisionControlTab.Title = 'Collision Control';
            app.xSliderLabel = uilabel(app.CollisionControlTab);
            app.xSliderLabel.Enable = 'off';
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.CollisionControlTab);
            app.xSlider.Limits = [-1 1];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.Enable = 'off';
            app.xSlider.Value = 0.8;
            app.ObjectPositionLabel = uilabel(app.CollisionControlTab);
            app.ObjectPositionLabel.Text = 'Object Position';
            app.ySliderLabel = uilabel(app.CollisionControlTab);
            app.ySliderLabel.Enable = 'off';
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.CollisionControlTab);
            app.ySlider.Limits = [-0.5 1.5];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySlider.Enable = 'off';
            app.zSliderLabel = uilabel(app.CollisionControlTab);
            app.zSliderLabel.Enable = 'off';
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.CollisionControlTab);
            app.zSlider.Limits = [0 1.5];
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.zSlider.Enable = 'off';
            app.zSlider.Value = 0.7;
            app.TogglehumanobstructionButton = uibutton(app.CollisionControlTab, 'state');
            app.TogglehumanobstructionButton.ValueChangedFcn = createCallbackFcn(app, @TogglehumanobstructionButtonValueChanged, true);
            app.TogglehumanobstructionButton.Text = 'Toggle human obstruction';
            app.LightCurtainLabel = uilabel(app.CollisionControlTab);
            app.LightCurtainLabel.Text = 'Light Curtain';
            app.CollisionDetectionLabel = uilabel(app.CollisionControlTab);
            app.CollisionDetectionLabel.Text = 'Collision Detection';
            app.TogglehandButton = uibutton(app.CollisionControlTab, 'state');
            app.TogglehandButton.ValueChangedFcn = createCallbackFcn(app, @TogglehandButtonValueChanged, true);
            app.TogglehandButton.Text = 'Toggle hand';
            app.EStop = uibutton(app.UIFigure, 'state');
            app.EStop.ValueChangedFcn = createCallbackFcn(app, @EStopValueChanged, true);
            app.EStop.Icon = fullfile(pathToMLAPP, 'images', 'E-Stop.png');
            app.EStop.Text = '';
            app.BOOTBOOZEBOTButton = uibutton(app.UIFigure, 'push');
            app.BOOTBOOZEBOTButton.ButtonPushedFcn = createCallbackFcn(app, @BOOTBOOZEBOTButtonPushed, true);
            app.BOOTBOOZEBOTButton.Text = 'BOOT BOOZE BOT';
            app.estopWarning = uilabel(app.UIFigure);
            app.estopWarning.Enable = 'off';
            app.estopWarning.Visible = 'off';
            app.estopWarning.Text = 'E-STOP PRESSED, RELEASE E-STOP TO CONTROL ROBOTS OR KILL PROGRAM';
            app.ResumeProgrammeButton = uibutton(app.UIFigure, 'state');
            app.ResumeProgrammeButton.ValueChangedFcn = createCallbackFcn(app, @ResumeProgrammeButtonValueChanged, true);
            app.ResumeProgrammeButton.Enable = 'off';
            app.ResumeProgrammeButton.Text = 'Resume Programme';
            app.EndProgrammeButton = uibutton(app.UIFigure, 'state');
            app.EndProgrammeButton.ValueChangedFcn = createCallbackFcn(app, @EndProgrammeButtonValueChanged, true);
            app.EndProgrammeButton.Enable = 'off';
            app.EndProgrammeButton.Text = 'End Programme';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControllerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end