classdef testerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StopButton   matlab.ui.control.StateButton
        PauseButton  matlab.ui.control.StateButton
        StartButton  matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function checkPauseStopStatus(app,flag)
            % FUNCTION TAKEN FROM https://au.mathworks.com/matlabcentral/answers/770738-stop-pause-a-simulation-in-app-designer-using-push-button

            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            % If flag input is included and =1, buttons will be reset to de-selected.
            % if flag input is included and =2, all buttons will be disabled (used during file writing and other processes)
            % if flag input is included and =3, all buttons will be enabled (used during file writing and other processes)

            if nargin > 1
                switch flag
                    case 1 % reset pause/stop button
                        app.PauseButton.Value = false;
                        PauseButtonValueChanged(app, [])
                        app.StopButton.Value = false;
                    case 2 % disable buttons
                        app.PauseButton.Enable = 'off';
                        app.StopButton.Enable = 'off';
                    case 3 % enable buttons
                        app.PauseButton.Enable = 'on';
                        app.StopButton.Enable = 'on';
                    otherwise
                        error('Unknown flag value.')
                end
                drawnow()
            else
                if app.PauseButton.Value
                    waitfor(app.PauseButton,'Value',false)
                end

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.PauseButton.Value
                app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
            else
                app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            a = tester(app);
            a.printCount;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end