classdef RobotControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResumeProgrammeButton           matlab.ui.control.StateButton
        estopWarning                    matlab.ui.control.Label
        BOOTBOOZEBOTButton              matlab.ui.control.Button
        EStop                           matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        AutonomousModeTab               matlab.ui.container.Tab
        StatusLabel                     matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        EStopPushedLamp_2               matlab.ui.control.Lamp
        EStopPushedLamp_2Label          matlab.ui.control.Label
        CollisionDetectedLamp           matlab.ui.control.Lamp
        CollisionDetectedLampLabel      matlab.ui.control.Label
        AutonomousModeLabel             matlab.ui.control.Label
        EStopPushedLamp                 matlab.ui.control.Lamp
        EStopPushedLampLabel            matlab.ui.control.Label
        SupermoistButton                matlab.ui.control.Button
        BigCreaturesPaleButton          matlab.ui.control.Button
        RooheysButton                   matlab.ui.control.Button
        ServeadeliciousbevvyLabel       matlab.ui.control.Label
        LashesButton                    matlab.ui.control.Button
        UR3ControllerTab                matlab.ui.container.Tab
        Joint6Slider                    matlab.ui.control.Slider
        Joint6SliderLabel               matlab.ui.control.Label
        Joint5Slider                    matlab.ui.control.Slider
        Joint5SliderLabel               matlab.ui.control.Label
        Joint4Slider                    matlab.ui.control.Slider
        Joint4SliderLabel               matlab.ui.control.Label
        Joint3Slider                    matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        EndeffectorspeedKnob            matlab.ui.control.Knob
        EndeffectorspeedKnobLabel       matlab.ui.control.Label
        JointmovementLabel              matlab.ui.control.Label
        CartesianmovementGlobalLabel    matlab.ui.control.Label
        LEFTButton                      matlab.ui.control.Button
        RIGHTButton                     matlab.ui.control.Button
        DOWNButton                      matlab.ui.control.Button
        UPButton                        matlab.ui.control.Button
        UR3ManualControlLabel           matlab.ui.control.Label
        CR5ControllerTab                matlab.ui.container.Tab
        Joint6Slider_2                  matlab.ui.control.Slider
        Joint6Slider_2Label             matlab.ui.control.Label
        Joint5Slider_2                  matlab.ui.control.Slider
        Joint5Slider_2Label             matlab.ui.control.Label
        Joint4Slider_2                  matlab.ui.control.Slider
        Joint4Slider_2Label             matlab.ui.control.Label
        Joint3Slider_2                  matlab.ui.control.Slider
        Joint3Slider_2Label             matlab.ui.control.Label
        Joint2Slider_2                  matlab.ui.control.Slider
        Joint2Slider_2Label             matlab.ui.control.Label
        Joint1Slider_2                  matlab.ui.control.Slider
        Joint1Slider_2Label             matlab.ui.control.Label
        EndeffectorspeedKnob_2          matlab.ui.control.Knob
        EndeffectorspeedKnob_2Label     matlab.ui.control.Label
        JointmovementLabel_2            matlab.ui.control.Label
        CartesianmovementGlobalLabel_2  matlab.ui.control.Label
        LEFTButton_2                    matlab.ui.control.Button
        RIGHTButton_2                   matlab.ui.control.Button
        DOWNButton_2                    matlab.ui.control.Button
        UPButton_2                      matlab.ui.control.Button
        CR5ManualControlLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        task % the class handle
    end
    
    methods (Access = public)

        function actionEStopStatus(app,flag)


            if app.EStop.Value
                % run the class in e-stop mode
                disp('running in e-stop mode ...');
                
                % wait for the EStop to be released
                while app.EStop.Value == true
                    pause(0.001);
                end

                % hand over control to the manual UI
                app.manualControl;

            end

        end

        function manualControl(app)

            while app.ResumeProgrammeButton.Value == false;
                pause(0.001)
            end

        end
    
        function status = getEStopStatus(app,event)

            status = app.EStop.Value;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EStop
        function EStopValueChanged(app, event)
            if app.EStop.Value
                app.EStop.Text = 'Release';
                app.estopWarning.Visible = "on";
                app.estopWarning.Enable = "on";
                app.ResumeProgrammeButton.Enable = "off"
                app.ResumeProgrammeButton.Text = "Release E-Stop to resume programme"
                
            else
                app.EStop.Text = '';
                app.estopWarning.Visible = "off";
                app.estopWarning.Enable = "off";
                app.ResumeProgrammeButton.Enable = "on"
                app.ResumeProgrammeButton.Text = "Resume Programme"
            end
        end

        % Button pushed function: LashesButton
        function LashesButtonPushed(app, event)
            app.task.chooseBeer(1);
            app.task.serveBeer;
        end

        % Button pushed function: BOOTBOOZEBOTButton
        function BOOTBOOZEBOTButtonPushed(app, event)
            app.task = TaskDemo(app);

        end

        % Value changed function: ResumeProgrammeButton
        function ResumeProgrammeButtonValueChanged(app, event)
            % value = app.ResumeProgrammeButton.Value;

            if app.EStop == false
                app.ResumeProgrammeButton.Enable = "off";
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AutonomousModeTab = uitab(app.TabGroup);
            app.AutonomousModeTab.Title = 'Autonomous Mode';
            app.LashesButton = uibutton(app.AutonomousModeTab, 'push');
            app.LashesButton.ButtonPushedFcn = createCallbackFcn(app, @LashesButtonPushed, true);
            app.LashesButton.Text = '350 Lashes';
            app.ServeadeliciousbevvyLabel = uilabel(app.AutonomousModeTab);
            app.ServeadeliciousbevvyLabel.Text = 'Serve a delicious bevvy:';
            app.RooheysButton = uibutton(app.AutonomousModeTab, 'push');
            app.RooheysButton.Text = 'Rooheys';
            app.BigCreaturesPaleButton = uibutton(app.AutonomousModeTab, 'push');
            app.BigCreaturesPaleButton.Text = 'Big Creatures Pale';
            app.SupermoistButton = uibutton(app.AutonomousModeTab, 'push');
            app.SupermoistButton.Text = 'Supermoist';
            app.EStopPushedLampLabel = uilabel(app.AutonomousModeTab);
            app.EStopPushedLampLabel.Text = 'Task Completed';
            app.EStopPushedLamp = uilamp(app.AutonomousModeTab);
            app.AutonomousModeLabel = uilabel(app.AutonomousModeTab);
            app.AutonomousModeLabel.Text = 'Autonomous Mode';
            app.CollisionDetectedLampLabel = uilabel(app.AutonomousModeTab);
            app.CollisionDetectedLampLabel.Text = 'Collision Detected';
            app.CollisionDetectedLamp = uilamp(app.AutonomousModeTab);
            app.EStopPushedLamp_2Label = uilabel(app.AutonomousModeTab);
            app.EStopPushedLamp_2Label.Text = 'E-Stop Pushed';
            app.EStopPushedLamp_2 = uilamp(app.AutonomousModeTab);
            app.RunningLampLabel = uilabel(app.AutonomousModeTab);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.AutonomousModeTab);
            app.StatusLabel = uilabel(app.AutonomousModeTab);
            app.StatusLabel.Text = 'Status';
            app.UR3ControllerTab = uitab(app.TabGroup);
            app.UR3ControllerTab.Title = 'UR3 Controller';
            app.UR3ManualControlLabel = uilabel(app.UR3ControllerTab);
            app.UR3ManualControlLabel.Text = 'UR3 Manual Control';
            app.UPButton = uibutton(app.UR3ControllerTab, 'push');
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UR3ControllerTab, 'push');
            app.DOWNButton.Text = 'DOWN';
            app.RIGHTButton = uibutton(app.UR3ControllerTab, 'push');
            app.RIGHTButton.Text = 'RIGHT';
            app.LEFTButton = uibutton(app.UR3ControllerTab, 'push');
            app.LEFTButton.Text = 'LEFT';
            app.CartesianmovementGlobalLabel = uilabel(app.UR3ControllerTab);
            app.CartesianmovementGlobalLabel.Text = 'Cartesian movement (Global):';
            app.JointmovementLabel = uilabel(app.UR3ControllerTab);
            app.JointmovementLabel.Text = 'Joint movement:';
            app.EndeffectorspeedKnobLabel = uilabel(app.UR3ControllerTab);
            app.EndeffectorspeedKnobLabel.Text = 'End effector speed';
            app.EndeffectorspeedKnob = uiknob(app.UR3ControllerTab, 'continuous');
            app.Joint1SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UR3ControllerTab);
            app.Joint2SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UR3ControllerTab);
            app.Joint3SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UR3ControllerTab);
            app.Joint4SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UR3ControllerTab);
            app.Joint5SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UR3ControllerTab);
            app.Joint6SliderLabel = uilabel(app.UR3ControllerTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UR3ControllerTab);
            app.CR5ControllerTab = uitab(app.TabGroup);
            app.CR5ControllerTab.Title = 'CR5 Controller';
            app.CR5ManualControlLabel = uilabel(app.CR5ControllerTab);
            app.CR5ManualControlLabel.Text = 'CR5 Manual Control';
            app.UPButton_2 = uibutton(app.CR5ControllerTab, 'push');
            app.UPButton_2.Text = 'UP';
            app.DOWNButton_2 = uibutton(app.CR5ControllerTab, 'push');
            app.DOWNButton_2.Text = 'DOWN';
            app.RIGHTButton_2 = uibutton(app.CR5ControllerTab, 'push');
            app.RIGHTButton_2.Text = 'RIGHT';
            app.LEFTButton_2 = uibutton(app.CR5ControllerTab, 'push');
            app.LEFTButton_2.Text = 'LEFT';
            app.CartesianmovementGlobalLabel_2 = uilabel(app.CR5ControllerTab);
            app.CartesianmovementGlobalLabel_2.Text = 'Cartesian movement (Global):';
            app.JointmovementLabel_2 = uilabel(app.CR5ControllerTab);
            app.JointmovementLabel_2.Text = 'Joint movement:';
            app.EndeffectorspeedKnob_2Label = uilabel(app.CR5ControllerTab);
            app.EndeffectorspeedKnob_2Label.Text = 'End effector speed';
            app.EndeffectorspeedKnob_2 = uiknob(app.CR5ControllerTab, 'continuous');
            app.Joint1Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_2 = uislider(app.CR5ControllerTab);
            app.Joint2Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_2 = uislider(app.CR5ControllerTab);
            app.Joint3Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_2 = uislider(app.CR5ControllerTab);
            app.Joint4Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider_2 = uislider(app.CR5ControllerTab);
            app.Joint5Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider_2 = uislider(app.CR5ControllerTab);
            app.Joint6Slider_2Label = uilabel(app.CR5ControllerTab);
            app.Joint6Slider_2Label.Text = 'Joint 6';
            app.Joint6Slider_2 = uislider(app.CR5ControllerTab);
            app.EStop = uibutton(app.UIFigure, 'state');
            app.EStop.ValueChangedFcn = createCallbackFcn(app, @EStopValueChanged, true);
            app.EStop.Text = '';
            app.EStop.Icon = fullfile(pathToMLAPP, 'images', 'E-Stop.png');
            app.BOOTBOOZEBOTButton = uibutton(app.UIFigure, 'push');
            app.BOOTBOOZEBOTButton.ButtonPushedFcn = createCallbackFcn(app, @BOOTBOOZEBOTButtonPushed, true);
            app.BOOTBOOZEBOTButton.Text = 'BOOT BOOZE BOT';
            app.estopWarning = uilabel(app.UIFigure);
            app.estopWarning.Enable = 'off';
            app.estopWarning.Visible = 'off';
            app.estopWarning.Text = 'E-STOP PRESSED, RELEASE E-STOP TO CONTROL ROBOTS OR KILL PROGRAM';
            app.ResumeProgrammeButton = uibutton(app.UIFigure, 'state');
            app.ResumeProgrammeButton.ValueChangedFcn = createCallbackFcn(app, @ResumeProgrammeButtonValueChanged, true);
            app.ResumeProgrammeButton.Text = 'Resume Programme';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControllerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end